classdef coffee_maker_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SpeedUpTimerButton      matlab.ui.control.Button
        EmptyPotButton          matlab.ui.control.Button
        TemperatureErrorButton  matlab.ui.control.Button
        TimerLabel              matlab.ui.control.Label
        TemperatureCGauge       matlab.ui.control.SemicircularGauge
        TemperatureCGaugeLabel  matlab.ui.control.Label
        CoffeeMakerPanel        matlab.ui.container.Panel
        PotImage                matlab.ui.control.Image
        IndicatorLamp           matlab.ui.control.Lamp
        AddWaterLampLabel       matlab.ui.control.Label
        Clock                   matlab.ui.control.Label
        BrewLaterButton         matlab.ui.control.Button
        BrewButton              matlab.ui.control.Button
        FillReservoirButton     matlab.ui.control.Button
        WaterLevelGauge         matlab.ui.control.SemicircularGauge
        WaterLevelGaugeLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        sfxLogic % Description
        doneBrewing
    end
    
    methods (Access = public)
        function results = getTime(app)
            c = clock;
            ampm = "AM";
            if(c(4) > 12)
                ampm = "PM";
                c(4) = c(4) - 12;
            end
            results = sprintf('%02d',c(4)) + ":" + sprintf('%02d',c(5)) + " " + ampm;
        end

        function timer = updateTimerValue(app, mins, secs)
            
            %if we have time on the timer, then we know it was set and we
            %need to brew the coffee after setting it to zero. If we don't
            %then this is an idle timer and therefore should not brew.
            brew = 0;
            if(secs > 0 || mins > 0)
                brew = 1;
            end
            
            %don't go below 0:00
            if(secs > 0 || mins > 0)
                if(secs > 0)
                    secs = secs - 1;
                else
                    secs = 59;
                    mins = mins - 1;
                end
            end
            
            app.sfxLogic.mins = mins;
            app.sfxLogic.secs = secs;

            timer = sprintf('%02d',mins) + ":" + sprintf('%02d',secs);

            %once the timer ends, if we said to brew, then brew
            if(secs == 0 && mins == 0 && brew == 1)
                PerformBrewCycle(app);
            end
        end

        function UpdateLCDLabel(app, time)
            if(time < 60)
                app.AddWaterLampLabel.Text = time + " mins";
            else
                app.AddWaterLampLabel.Text = (time/60) + " hrs";
            end
        end

        function PerformBrewCycle(app)
            %if no water, don't attempt to brew
            if(app.WaterLevelGauge.Value == 0)
               WaterError(app.sfxLogic);
               BackToIdle(app.sfxLogic);
            else 
                Brew(app.sfxLogic);
                while(app.TemperatureCGauge.Value < 95)
                    %Wait for the temp to get warm enough
                    pause(0.1)
                end
                BeginBrew(app.sfxLogic);
                while(app.sfxLogic.potCounter < 6)
                   ContinueBrew(app.sfxLogic);
                end
                DoneBrewing(app.sfxLogic);
                app.doneBrewing = true;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            edit coffee_maker.sfx;
            app.sfxLogic = coffee_maker('app', app);
            app.sfxLogic.timerTimes = [15 30 45 60 90 120];
            app.sfxLogic.timerCounter = 0;
            app.Clock.Text = getTime(app);
            %initialize to room temperature (21 C)
            app.TemperatureCGauge.Value = 21;
            app.doneBrewing = false;
        end

        % Button pushed function: FillReservoirButton
        function FillReservoirButtonPushed(app, event)
            FillWaterButton(app.sfxLogic);
            BackToIdle(app.sfxLogic);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.sfxLogic)
            delete(app)
        end

        % Button pushed function: BrewButton
        function BrewButtonPushed(app, event)
            if(app.doneBrewing == true)
                BackToIdle(app.sfxLogic);
            else
                %if we set the timer to something, then that means we want
                %to wait to brew. Set zero after updating to tell it 
                %to Brew next time
                if(app.sfxLogic.timerCounter > 0)
                    UpdateTimer(app.sfxLogic);
                    app.sfxLogic.timerCounter = 0;
                    BackToIdle(app.sfxLogic);
                else
                    PerformBrewCycle(app);
                end
            end
        end

        % Button pushed function: BrewLaterButton
        function BrewLaterButtonPushed(app, event)
            BrewLater(app.sfxLogic);
        end

        % Button pushed function: TemperatureErrorButton
        function TemperatureErrorButtonPushed(app, event)
            %Simulates a temperature fault
            app.TemperatureCGauge.Value = 110;
            TemperatureError(app.sfxLogic);
            %Back to room temp
            app.TemperatureCGauge.Value = 21;
            BackToIdle(app.sfxLogic);
        end

        % Button pushed function: EmptyPotButton
        function EmptyPotButtonPushed(app, event)
            app.PotImage.ImageSource = "coffee_pot_empty.jpg";
        end

        % Button pushed function: SpeedUpTimerButton
        function SpeedUpTimerButtonPushed(app, event)
            updateTimerValue(app, 0, 10);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WaterLevelGaugeLabel = uilabel(app.UIFigure);
            app.WaterLevelGaugeLabel.Text = 'Water Level';
            app.WaterLevelGauge = uigauge(app.UIFigure, 'semicircular');
            app.FillReservoirButton = uibutton(app.UIFigure, 'push');
            app.FillReservoirButton.ButtonPushedFcn = createCallbackFcn(app, @FillReservoirButtonPushed, true);
            app.FillReservoirButton.Text = 'Fill Reservoir';
            app.CoffeeMakerPanel = uipanel(app.UIFigure);
            app.CoffeeMakerPanel.Title = 'Coffee Maker';
            app.BrewButton = uibutton(app.CoffeeMakerPanel, 'push');
            app.BrewButton.ButtonPushedFcn = createCallbackFcn(app, @BrewButtonPushed, true);
            app.BrewButton.Text = 'Brew';
            app.BrewLaterButton = uibutton(app.CoffeeMakerPanel, 'push');
            app.BrewLaterButton.ButtonPushedFcn = createCallbackFcn(app, @BrewLaterButtonPushed, true);
            app.BrewLaterButton.Text = 'Brew Later';
            app.Clock = uilabel(app.CoffeeMakerPanel);
            app.Clock.Text = 'Time';
            app.AddWaterLampLabel = uilabel(app.CoffeeMakerPanel);
            app.AddWaterLampLabel.Text = 'Add Water';
            app.IndicatorLamp = uilamp(app.CoffeeMakerPanel);
            app.PotImage = uiimage(app.CoffeeMakerPanel);
            app.PotImage.ImageSource = 'coffee_pot_empty.jpg';
            app.TemperatureCGaugeLabel = uilabel(app.UIFigure);
            app.TemperatureCGaugeLabel.Text = 'Temperature (C)';
            app.TemperatureCGauge = uigauge(app.UIFigure, 'semicircular');
            app.TemperatureCGauge.Limits = [0 120];
            app.TemperatureCGauge.MajorTicks = [0 20 40 60 80 100 120];
            app.TemperatureCGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120];
            app.TimerLabel = uilabel(app.UIFigure);
            app.TimerLabel.Text = 'Timer';
            app.TemperatureErrorButton = uibutton(app.UIFigure, 'push');
            app.TemperatureErrorButton.ButtonPushedFcn = createCallbackFcn(app, @TemperatureErrorButtonPushed, true);
            app.TemperatureErrorButton.Text = 'Temperature Error';
            app.EmptyPotButton = uibutton(app.UIFigure, 'push');
            app.EmptyPotButton.ButtonPushedFcn = createCallbackFcn(app, @EmptyPotButtonPushed, true);
            app.EmptyPotButton.Text = 'Empty Pot';
            app.SpeedUpTimerButton = uibutton(app.UIFigure, 'push');
            app.SpeedUpTimerButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedUpTimerButtonPushed, true);
            app.SpeedUpTimerButton.Text = 'Speed Up Timer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coffee_maker_simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end