classdef elevator_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DoorNotBlockedLabel  matlab.ui.control.Label
        BlockDoorsButton     matlab.ui.control.Button
        DownButton           matlab.ui.control.Button
        UpButton             matlab.ui.control.Button
        Button2              matlab.ui.control.Button
        Button1              matlab.ui.control.Button
        Floor2Label          matlab.ui.control.Label
        Floor1Label          matlab.ui.control.Label
        Car                  matlab.ui.control.Image
    end

    
    properties (Access = private)
        sfxLogic % Description
        states
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        edit elevator.sfx;
            app.sfxLogic = elevator('app',app);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            ButtonUp(app.sfxLogic);
            OpenDoors(app.sfxLogic);
            while(app.sfxLogic.doorClosed ~= true)
                OpenDoors(app.sfxLogic);
                pause(5);
            end
            Off(app.sfxLogic);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            ButtonDown(app.sfxLogic);
            OpenDoors(app.sfxLogic);
            while(app.sfxLogic.doorClosed ~= true)
                OpenDoors(app.sfxLogic);
                pause(5);
            end
            Off(app.sfxLogic);
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            Button1(app.sfxLogic);
            OpenDoors(app.sfxLogic);
            while(app.sfxLogic.doorClosed ~= true)
                OpenDoors(app.sfxLogic);
                pause(5);
            end
            Off(app.sfxLogic);
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            Button2(app.sfxLogic);
            OpenDoors(app.sfxLogic);
            while(app.sfxLogic.doorClosed ~= true)
                OpenDoors(app.sfxLogic);
                pause(5);
            end
            Off(app.sfxLogic);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.sfxLogic)
            delete(app)
        end

        % Button pushed function: BlockDoorsButton
        function BlockDoorsButtonPushed(app, event)
            if(app.sfxLogic.blocked == false)
                app.sfxLogic.blocked = true;
                app.DoorNotBlockedLabel.Text = "Door Blocked!";
                app.DoorNotBlockedLabel.FontColor = 'red';
                app.BlockDoorsButton.Text = "Unblock Doors";
            else
                app.DoorNotBlockedLabel.Text = "Door Not Blocked";
                app.DoorNotBlockedLabel.FontColor = 'green';
                app.sfxLogic.blocked = false;
                app.BlockDoorsButton.Text = "Block Doors";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Car = uiimage(app.UIFigure);
            app.Car.ImageSource = 'elevator_closed.png';
            app.Floor1Label = uilabel(app.UIFigure);
            app.Floor1Label.Text = 'Floor 1';
            app.Floor2Label = uilabel(app.UIFigure);
            app.Floor2Label.Text = 'Floor 2';
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '1';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '2';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.BlockDoorsButton = uibutton(app.UIFigure, 'push');
            app.BlockDoorsButton.ButtonPushedFcn = createCallbackFcn(app, @BlockDoorsButtonPushed, true);
            app.BlockDoorsButton.Text = 'Block Doors';
            app.DoorNotBlockedLabel = uilabel(app.UIFigure);
            app.DoorNotBlockedLabel.Text = 'Door Not Blocked';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = elevator_simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end